classdef AN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        Label            matlab.ui.control.Label
        Button           matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        xEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.EditField
        yEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.EditField
        evolventaLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.cla;
            sym t;
            t = sym('t');
            x1 = app.xEditField.Value;
            y1 = app.yEditField.Value;
            x = str2sym(x1);
            y = str2sym(y1);
            a=2;
            evolventa(x, y, t, a)
            function [] = evolventa(x, y, t, a)
            dx = diff(x, t);
            dy = diff(y, t);
            f = sqrt(dx^2 + dy^2);
            f = simplify(f);
            f = vpa(f);
            J = int(f, t);
            J = J - subs(J, a);
            x1 = x - (dx .* J)/f;
            y1 = y - (dy .* J)/f;
            X = simplify(x1)
            Y = simplify(y1)
            grafik(X, Y, -10, 10);
            end

            function [] = grafik(x, y, alpha, beta)
            ts = [alpha:0.01:beta];
            xs = vpa(subs(x, ts));
            ys = vpa(subs(y, ts));

            plot(app.UIAxes, xs, ys);
            end
            app.Label_2.Text = char(x1);
            app.Label.Text = char(y1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x = ';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y = ';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.evolventaLabel = uilabel(app.UIFigure);
            app.evolventaLabel.Text = 'evolventa : ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end